classdef ChisepToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        DataLoadPanel                   matlab.ui.container.Panel
        DataLoadGridLayout              matlab.ui.container.GridLayout
        multiechoGREEditFieldLabel      matlab.ui.control.Label
        multiechoGREEditField           matlab.ui.control.EditField
        LoadButton1                     matlab.ui.control.Button
        ParametersPanel                 matlab.ui.container.Panel
        ParametersGridLayout            matlab.ui.container.GridLayout
        CenterfreqHzEditFieldLabel      matlab.ui.control.Label
        CenterfreqHzEditField           matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        TEmsDropDownLabel               matlab.ui.control.Label
        TEmsDropDown                    matlab.ui.control.DropDown
        LoadButton7                     matlab.ui.control.Button
        B0directionLabel                matlab.ui.control.Label
        B0directionEditField            matlab.ui.control.EditField
        VoxelsizeLabel                  matlab.ui.control.Label
        VoxelsizeEditField              matlab.ui.control.EditField
        OutputdirectoryEditFieldLabel   matlab.ui.control.Label
        OutputdirectoryEditField        matlab.ui.control.EditField
        LoadButton6                     matlab.ui.control.Button
        InputsLabel                     matlab.ui.control.Label
        MGREDataTypeDropDown            matlab.ui.control.DropDown
        OptionalinputsPanel             matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        R2sEditField                    matlab.ui.control.EditField
        R2sEditFieldLabel               matlab.ui.control.Label
        R2pEditField                    matlab.ui.control.EditField
        R2HzLabel                       matlab.ui.control.Label
        QSMEditField                    matlab.ui.control.EditField
        QSMppmEditFieldLabel            matlab.ui.control.Label
        BrainmaskEditField              matlab.ui.control.EditField
        BrainmaskEditFieldLabel         matlab.ui.control.Label
        LoadoptionalinputsmatButton     matlab.ui.control.Button
        LocalFieldHzEditFieldLabel      matlab.ui.control.Label
        LocalFieldEditField             matlab.ui.control.EditField
        DeleteButton                    matlab.ui.control.Button
        DeleteButton_2                  matlab.ui.control.Button
        DeleteButton_3                  matlab.ui.control.Button
        DeleteButton_4                  matlab.ui.control.Button
        DeleteButton_5                  matlab.ui.control.Button
        multiechoGREEditField_pha       matlab.ui.control.EditField
        MagnLabel                       matlab.ui.control.Label
        PhaseLabel                      matlab.ui.control.Label
        LoadButton1_pha                 matlab.ui.control.Button
        ImageDisplayPanel               matlab.ui.container.Panel
        ImageDisplayGridLayout          matlab.ui.container.GridLayout
        SliceLabel                      matlab.ui.control.Label
        SliceSlider                     matlab.ui.control.Slider
        DataDropDown                    matlab.ui.control.DropDown
        DataDropDownLabel               matlab.ui.control.Label
        EchoDropDown                    matlab.ui.control.DropDown
        EchoLabel                       matlab.ui.control.Label
        RminEditFieldLabel              matlab.ui.control.Label
        RminEditField                   matlab.ui.control.EditField
        RmaxEditFieldLabel              matlab.ui.control.Label
        RmaxEditField                   matlab.ui.control.EditField
        VesselsegmentationButton        matlab.ui.control.Button
        ImageDisplay                    matlab.ui.control.UIAxes
        ProcessingOptionPanel           matlab.ui.container.Panel
        ProcessingOptionGridLayout      matlab.ui.container.GridLayout
        PhaseunwrappingechocombinationLabel  matlab.ui.control.Label
        PhaseunwrappingechocombinationDropDown  matlab.ui.control.DropDown
        BackgroundfieldremovalLabel     matlab.ui.control.Label
        BackgroundfieldremovalDropDown  matlab.ui.control.DropDown
        TukeywindowfiltersizeEditFieldLabel  matlab.ui.control.Label
        TukeywindowfiltersizeEditField  matlab.ui.control.NumericEditField
        StartChiseparationButton        matlab.ui.control.Button
        ChiseparationLabel              matlab.ui.control.Label
        ChiseparationDropDown           matlab.ui.control.DropDown
        R2fittingDropDownLabel          matlab.ui.control.Label
        R2fittingDropDown               matlab.ui.control.DropDown
        PhaseInverseCheckBox            matlab.ui.control.CheckBox
        ResolutionPreserveCheckBox      matlab.ui.control.CheckBox
        MultiechomagnitudedenoisingMGREforR2mappingCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        HomeDirectory
        OutputDirectory
        
        Input = struct(); 
        Output = struct(); 
        Params = struct(); % 
        RunOptions = struct(); % 
        
        CurrSlice = 1;
        InitialCoord
        Lev;
        Win;
        WLAdjCoe;
        range;
        
        nifti_template = {};
    end
    
    methods (Access = private)
        function ScrollSlice(app,~,event)

            uiSlider = findobj(app.UIFigure,'Type','uislider', '-and', 'Tag', 'Display');
            
            if event.VerticalScrollCount < 0
                app.CurrSlice = app.CurrSlice + 1;
                if(app.CurrSlice>uiSlider.Limits(2)); app.CurrSlice = uiSlider.Limits(2); end
                uiSlider.Value = app.CurrSlice;
            elseif event.VerticalScrollCount > 0
                app.CurrSlice = app.CurrSlice - 1;
                if(app.CurrSlice<1); app.CurrSlice = 1; end
                uiSlider.Value = app.CurrSlice;
            end

            UpdateImageDisplay(app);


        end


        function WinLevAdj(app,~,~)
            PosDiff = app.UIFigure.CurrentPoint - app.InitialCoord;
            
            app.R2WL();
            app.Win = app.Win + PosDiff(1) * app.WLAdjCoe;
            app.Lev = app.Lev - PosDiff(2) * app.WLAdjCoe;
            app.WL2R();
            tag = app.DataDropDown.Value;
            type = UpdateImageDisplay(app);

            if(abs(app.range(1))>=1)
                set(app.RminEditField, 'Value', sprintf('%6.2f',app.range(1)));
            else
                set(app.RminEditField, 'Value', sprintf('%1.4f',app.range(1)));
            end
            if(abs(app.range(2))>=1)
                set(app.RmaxEditField, 'Value', sprintf('%6.2f',app.range(2)));
            else
                set(app.RmaxEditField, 'Value', sprintf('%1.4f',app.range(2)));
            end
            app.(type).(tag).range = app.range;
        end

        function mouseClick(app,~,event)
            cp = app.UIFigure.CurrentPoint;
            isInImage = cp(1) >= app.ImageDisplay.Position(1)+app.ImageDisplayPanel.Position(1) && ...
                cp(1) <= sum(app.ImageDisplay.Position([1,3]))+app.ImageDisplayPanel.Position(1) && ...
                cp(2) >= app.ImageDisplay.Position(2)+app.ImageDisplayPanel.Position(2) && ...
                cp(2) <= sum(app.ImageDisplay.Position([2,4]))+app.ImageDisplayPanel.Position(2);
            isInDispPanel = cp(1) >= app.ImageDisplayPanel.Position(1) && ...
                cp(1) <= sum(app.ImageDisplayPanel.Position([1,3])) && ...
                cp(2) >= app.ImageDisplayPanel.Position(2) && ...
                cp(2) <= sum(app.ImageDisplayPanel.Position([2,4]));
            switch event.Source.SelectionType
                case 'alt'
                    app.InitialCoord = cp;
                    if isInImage
                        set(app.UIFigure, 'WindowButtonMotionFcn', @app.WinLevAdj);
                    end
                case 'normal'
                    if isInDispPanel
                        set(app.UIFigure, 'WindowScrollWheelFcn', @app.ScrollSlice);
                        disableDefaultInteractivity(app.ImageDisplay)
                    else
                        set(app.UIFigure, 'WindowScrollWheelFcn', '');
%                         enableDefaultInteractivity(app.ImageDisplay)
                    end
            end
        end

        
        function R2WL(app)
            app.Lev = (app.range(1)+app.range(2))/2;
            app.Win = app.range(2)-app.range(1);
            if(app.Win<0)
                app.Win=eps;
            end
        end
        function WL2R(app)
            app.range(1) = app.Lev-app.Win/2;
            app.range(2) = app.Lev+app.Win/2;
            if(app.range(2)<app.range(1))
                app.range(2) = app.range(1)+eps;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, HomeDirectory)
            app.HomeDirectory = HomeDirectory;
            set(app.UIFigure,'WindowButtonDownFcn',@app.mouseClick);
            InitializeData(app);
        end

        % Button pushed function: LoadButton1, LoadButton1_pha, 
        % LoadButton6, LoadButton7, LoadoptionalinputsmatButton
        function LoadButtonPushed(app, event)
            LoadButtonPushedCallback(app, event);
        end

        % Value changed function: TEmsDropDown
        function TEDropDownValueChanged(app, event)
            TEDropDownValueChangedCallback(app,event)
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            DataDropDownValueChangedCallback(app,event);
        end

        % Value changed function: EchoDropDown
        function EchoDropDownValueChanged(app, event)
            EchoDropDownValueChangedCallback(app, event)
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.CurrSlice = round(event.Value);
            
            set(app.UIFigure, 'WindowScrollWheelFcn', @app.ScrollSlice);
            disableDefaultInteractivity(app.ImageDisplay)
            
            UpdateImageDisplay(app);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            set(app.UIFigure, 'WindowButtonMotionFcn', '');
        end

        % Value changed function: RminEditField
        function RminEditFieldValueChanged(app, event)
            value = app.RminEditField.Value;
            tag = app.DataDropDown.Value;
            if(isfield(app.Input,tag))
                app.Input.(tag).range(1) = str2double(value);
            else
                app.Output.(tag).range(1) = str2double(value);
            end
            app.range(1) = str2double(value);
            UpdateImageDisplay(app);
        end

        % Value changed function: RmaxEditField
        function RmaxEditFieldValueChanged(app, event)
            value = app.RmaxEditField.Value;
            tag = app.DataDropDown.Value;            
            if(isfield(app.Input,tag))
                app.Input.(tag).range(1) = str2double(value);
            else
                app.Output.(tag).range(1) = str2double(value);
            end
            app.range(2) = str2double(value);
            UpdateImageDisplay(app);
        end

        % Value changed function: B0directionEditField, 
        % CenterfreqHzEditField, EditField, VoxelsizeEditField
        function ParameterEditFieldValueChanged(app, event)
            ParameterEditFieldValueChangedCallback(app, event);
        end

        % Button pushed function: StartChiseparationButton
        function StartChiseparationButtonPushed(app, event)
            RunChiSeparation(app, event);
        end

        % Value changed function: BrainmaskEditField, 
        % OutputdirectoryEditField, QSMEditField, R2pEditField, 
        % R2sEditField, multiechoGREEditField
        function DataEditFieldValueChanged(app, event)
            DataEditFieldValueChangedCallback(app, event)            
        end

        % Button pushed function: DeleteButton, DeleteButton_2, 
        % DeleteButton_3, DeleteButton_4, DeleteButton_5
        function DeleteButtonPushed(app, event)
            DeleteButtonPushedCallback(app,event);
        end

        % Value changed function: MGREDataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if(strcmp(event.Value,'.nii'))
                app.multiechoGREEditField_pha.Visible = true;
                app.LoadButton1_pha.Visible = true;
                app.multiechoGREEditField_pha.Visible = true;
                app.MagnLabel.Visible = true;
                app.PhaseLabel.Visible = true;
            else
                app.multiechoGREEditField_pha.Visible = false;
                app.LoadButton1_pha.Visible = false;
                app.multiechoGREEditField_pha.Visible = false;
                app.MagnLabel.Visible = false;
                app.PhaseLabel.Visible = false;
            end
        end

        % Button pushed function: VesselsegmentationButton
        function VesselsegmentationButtonPushed(app, event)
            if(isempty(app.Output))
                msgbox("No chi-separation data available for vessel segmentation. Please conduct chi-separation first")
                return
            end
            if(isfield(app.Output,'R2s'))
                R2s = app.Output.R2s.data;
            elseif(isfield(app.Input,'R2s'))
                R2s = app.Input.R2s.data;
            else
                msgbox("No R2* data available for vessel segmentation")
            end
            
            if(isfield(app.Output,'Mask_VSHARP'))
                Mask = app.Output.Mask_VSHARP.data;
            elseif(isfield(app.Output,'Mask'))
                Mask = app.Output.Mask.data;
            else
                Mask = app.Input.Mask.data;
            end
            data = struct('x_para',app.Output.ChiPara.data,'x_dia',app.Output.ChiDia.data,'r2star',R2s,'BrainMask',Mask);
            output_dir = [app.OutputDirectory filesep 'chisep_output_' app.RunOptions.datetime];
            vesselSeg_Toolbox(data, output_dir);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataLoadPanel = uipanel(app.GridLayout);
            app.DataLoadPanel.Title = 'Load Data';
            app.DataLoadGridLayout = uigridlayout(app.DataLoadPanel);
            app.multiechoGREEditFieldLabel = uilabel(app.DataLoadGridLayout);
            app.multiechoGREEditFieldLabel.Text = 'multi-echo GRE';
            app.multiechoGREEditField = uieditfield(app.DataLoadGridLayout, 'text');
            app.multiechoGREEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.multiechoGREEditField.Tag = 'MGRE';
            app.LoadButton1 = uibutton(app.DataLoadGridLayout, 'push');
            app.LoadButton1.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton1.Tag = 'MGRE';
            app.LoadButton1.Text = 'Load';
            app.ParametersPanel = uipanel(app.DataLoadGridLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.ParametersGridLayout = uigridlayout(app.ParametersPanel);
            app.CenterfreqHzEditFieldLabel = uilabel(app.ParametersGridLayout);
            app.CenterfreqHzEditFieldLabel.Text = 'Center freq. (Hz)';
            app.CenterfreqHzEditField = uieditfield(app.ParametersGridLayout, 'numeric');
            app.CenterfreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @ParameterEditFieldValueChanged, true);
            app.CenterfreqHzEditField.Tag = 'CF';
            app.EditField = uieditfield(app.ParametersGridLayout, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ParameterEditFieldValueChanged, true);
            app.EditField.Tag = 'TE';
            app.TEmsDropDownLabel = uilabel(app.ParametersGridLayout);
            app.TEmsDropDownLabel.Text = 'TE (ms)';
            app.TEmsDropDown = uidropdown(app.ParametersGridLayout);
            app.TEmsDropDown.Items = {'TE 1'};
            app.TEmsDropDown.ValueChangedFcn = createCallbackFcn(app, @TEDropDownValueChanged, true);
            app.TEmsDropDown.Tag = 'TE';
            app.TEmsDropDown.Value = 'TE 1';
            app.LoadButton7 = uibutton(app.ParametersGridLayout, 'push');
            app.LoadButton7.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton7.Tag = 'Params';
            app.LoadButton7.Tooltip = {'Load from a .mat file containing parameters with the following names:'; 'CF, B0dir, VoxelSize, TE'};
            app.LoadButton7.Text = 'Load parameters (.mat)';
            app.B0directionLabel = uilabel(app.ParametersGridLayout);
            app.B0directionLabel.Text = 'B0 direction';
            app.B0directionEditField = uieditfield(app.ParametersGridLayout, 'text');
            app.B0directionEditField.ValueChangedFcn = createCallbackFcn(app, @ParameterEditFieldValueChanged, true);
            app.B0directionEditField.Tag = 'B0dir';
            app.B0directionEditField.Value = '[]';
            app.VoxelsizeLabel = uilabel(app.ParametersGridLayout);
            app.VoxelsizeLabel.Text = 'Voxel size';
            app.VoxelsizeEditField = uieditfield(app.ParametersGridLayout, 'text');
            app.VoxelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ParameterEditFieldValueChanged, true);
            app.VoxelsizeEditField.Tag = 'VoxelSize';
            app.VoxelsizeEditField.Value = '[]';
            app.OutputdirectoryEditFieldLabel = uilabel(app.DataLoadGridLayout);
            app.OutputdirectoryEditFieldLabel.Text = 'Output directory';
            app.OutputdirectoryEditField = uieditfield(app.DataLoadGridLayout, 'text');
            app.OutputdirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.OutputdirectoryEditField.Tag = 'OutDir';
            app.LoadButton6 = uibutton(app.DataLoadGridLayout, 'push');
            app.LoadButton6.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton6.Tag = 'OutDir';
            app.LoadButton6.Text = 'Load';
            app.InputsLabel = uilabel(app.DataLoadGridLayout);
            app.InputsLabel.Text = 'Inputs';
            app.MGREDataTypeDropDown = uidropdown(app.DataLoadGridLayout);
            app.MGREDataTypeDropDown.Items = {'DICOM', '.nii'};
            app.MGREDataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.MGREDataTypeDropDown.Tag = 'MGRE';
            app.MGREDataTypeDropDown.Value = 'DICOM';
            app.OptionalinputsPanel = uipanel(app.DataLoadGridLayout);
            app.OptionalinputsPanel.Title = 'Optional inputs';
            app.GridLayout2 = uigridlayout(app.OptionalinputsPanel);
            app.R2sEditField = uieditfield(app.GridLayout2, 'text');
            app.R2sEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.R2sEditField.Tag = 'R2s';
            app.R2sEditField.Editable = 'off';
            app.R2sEditFieldLabel = uilabel(app.GridLayout2);
            app.R2sEditFieldLabel.Text = 'R2* (Hz)';
            app.R2pEditField = uieditfield(app.GridLayout2, 'text');
            app.R2pEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.R2pEditField.Tag = 'R2p';
            app.R2pEditField.Editable = 'off';
            app.R2HzLabel = uilabel(app.GridLayout2);
            app.R2HzLabel.Text = 'R2'' (Hz)';
            app.QSMEditField = uieditfield(app.GridLayout2, 'text');
            app.QSMEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.QSMEditField.Tag = 'QSM';
            app.QSMEditField.Editable = 'off';
            app.QSMppmEditFieldLabel = uilabel(app.GridLayout2);
            app.QSMppmEditFieldLabel.Text = 'QSM (ppm)';
            app.BrainmaskEditField = uieditfield(app.GridLayout2, 'text');
            app.BrainmaskEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.BrainmaskEditField.Tag = 'Mask';
            app.BrainmaskEditField.Editable = 'off';
            app.BrainmaskEditFieldLabel = uilabel(app.GridLayout2);
            app.BrainmaskEditFieldLabel.Text = 'Brain mask';
            app.LoadoptionalinputsmatButton = uibutton(app.GridLayout2, 'push');
            app.LoadoptionalinputsmatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadoptionalinputsmatButton.Tag = 'OptInput';
            app.LoadoptionalinputsmatButton.Tooltip = {'Load from a .mat file containing more than one of the parameters with the following names:'; 'R2s, R2p, LocalField, QSM, Mask'};
            app.LoadoptionalinputsmatButton.Text = 'Load optional inputs (.mat)';
            app.LocalFieldHzEditFieldLabel = uilabel(app.GridLayout2);
            app.LocalFieldHzEditFieldLabel.Text = 'Local Field (Hz)';
            app.LocalFieldEditField = uieditfield(app.GridLayout2, 'text');
            app.LocalFieldEditField.Tag = 'LocalField';
            app.LocalFieldEditField.Editable = 'off';
            app.DeleteButton = uibutton(app.GridLayout2, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Tag = 'R2s';
            app.DeleteButton.Text = 'Delete';
            app.DeleteButton_2 = uibutton(app.GridLayout2, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_2.Tag = 'R2p';
            app.DeleteButton_2.Text = 'Delete';
            app.DeleteButton_3 = uibutton(app.GridLayout2, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_3.Tag = 'LocalField';
            app.DeleteButton_3.Text = 'Delete';
            app.DeleteButton_4 = uibutton(app.GridLayout2, 'push');
            app.DeleteButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_4.Tag = 'QSM';
            app.DeleteButton_4.Text = 'Delete';
            app.DeleteButton_5 = uibutton(app.GridLayout2, 'push');
            app.DeleteButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton_5.Tag = 'Mask';
            app.DeleteButton_5.Text = 'Delete';
            app.multiechoGREEditField_pha = uieditfield(app.DataLoadGridLayout, 'text');
            app.multiechoGREEditField_pha.Tag = 'MGRE_Pha';
            app.multiechoGREEditField_pha.Visible = 'off';
            app.MagnLabel = uilabel(app.DataLoadGridLayout);
            app.MagnLabel.Visible = 'off';
            app.MagnLabel.Text = 'Magn.';
            app.PhaseLabel = uilabel(app.DataLoadGridLayout);
            app.PhaseLabel.Visible = 'off';
            app.PhaseLabel.Text = 'Phase';
            app.LoadButton1_pha = uibutton(app.DataLoadGridLayout, 'push');
            app.LoadButton1_pha.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton1_pha.Tag = 'MGRE_Pha';
            app.LoadButton1_pha.Visible = 'off';
            app.LoadButton1_pha.Text = 'Load';
            app.ImageDisplayPanel = uipanel(app.GridLayout);
            app.ImageDisplayPanel.Title = 'Image Display';
            app.ImageDisplayGridLayout = uigridlayout(app.ImageDisplayPanel);
            app.SliceLabel = uilabel(app.ImageDisplayGridLayout);
            app.SliceLabel.Text = 'Slice ( ## / ## )';
            app.SliceSlider = uislider(app.ImageDisplayGridLayout);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Tag = 'Display';
            app.DataDropDown = uidropdown(app.ImageDisplayGridLayout);
            app.DataDropDown.Items = {};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Tag = 'ImageData';
            app.DataDropDown.Value = {};
            app.DataDropDownLabel = uilabel(app.ImageDisplayGridLayout);
            app.DataDropDownLabel.Text = 'Data';
            app.EchoDropDown = uidropdown(app.ImageDisplayGridLayout);
            app.EchoDropDown.Items = {'1'};
            app.EchoDropDown.ValueChangedFcn = createCallbackFcn(app, @EchoDropDownValueChanged, true);
            app.EchoDropDown.Tag = 'EchoNumber';
            app.EchoDropDown.Value = '1';
            app.EchoLabel = uilabel(app.ImageDisplayGridLayout);
            app.EchoLabel.Text = 'Echo';
            app.RminEditFieldLabel = uilabel(app.ImageDisplayGridLayout);
            app.RminEditFieldLabel.Text = 'Rmin';
            app.RminEditField = uieditfield(app.ImageDisplayGridLayout, 'text');
            app.RminEditField.ValueChangedFcn = createCallbackFcn(app, @RminEditFieldValueChanged, true);
            app.RmaxEditFieldLabel = uilabel(app.ImageDisplayGridLayout);
            app.RmaxEditFieldLabel.Text = 'Rmax';
            app.RmaxEditField = uieditfield(app.ImageDisplayGridLayout, 'text');
            app.RmaxEditField.ValueChangedFcn = createCallbackFcn(app, @RmaxEditFieldValueChanged, true);
            app.VesselsegmentationButton = uibutton(app.ImageDisplayGridLayout, 'push');
            app.VesselsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @VesselsegmentationButtonPushed, true);
            app.VesselsegmentationButton.Text = {'Vessel '; 'segmentation'};
            app.ImageDisplay = uiaxes(app.ImageDisplayGridLayout);
            app.ImageDisplay.XTick = [];
            app.ImageDisplay.YTick = [];
            app.ImageDisplay.YTickLabel = '';
            app.ProcessingOptionPanel = uipanel(app.GridLayout);
            app.ProcessingOptionPanel.Title = 'Processing options';
            app.ProcessingOptionGridLayout = uigridlayout(app.ProcessingOptionPanel);
            app.PhaseunwrappingechocombinationLabel = uilabel(app.ProcessingOptionGridLayout);
            app.PhaseunwrappingechocombinationLabel.Text = {'Phase unwrapping & '; 'echo combination:'};
            app.PhaseunwrappingechocombinationDropDown = uidropdown(app.ProcessingOptionGridLayout);
            app.PhaseunwrappingechocombinationDropDown.Items = {'ROMEO + weighted echo averaging', 'nonlinear complex fitting + SEGUE', 'Use preprocessed local field map'};
            app.PhaseunwrappingechocombinationDropDown.Tag = 'Unwrap';
            app.PhaseunwrappingechocombinationDropDown.Tooltip = {'Default for chi-sepnet is ROMEO + weighted echo averaging.'};
            app.PhaseunwrappingechocombinationDropDown.Value = 'ROMEO + weighted echo averaging';
            app.BackgroundfieldremovalLabel = uilabel(app.ProcessingOptionGridLayout);
            app.BackgroundfieldremovalLabel.Text = 'Background field removal:';
            app.BackgroundfieldremovalDropDown = uidropdown(app.ProcessingOptionGridLayout);
            app.BackgroundfieldremovalDropDown.Items = {'V-SHARP', 'Use preprocessed local field map'};
            app.BackgroundfieldremovalDropDown.Tag = 'BFR';
            app.BackgroundfieldremovalDropDown.Tooltip = {'V-SHARP: Less sensitive to mask imperfection. Brain mask is eroded by one voxel.'; ''; 'PDF: Use when whole brain mapping (including cortex) is important. Requires a good brain mask'};
            app.BackgroundfieldremovalDropDown.Value = 'V-SHARP';
            app.TukeywindowfiltersizeEditFieldLabel = uilabel(app.ProcessingOptionGridLayout);
            app.TukeywindowfiltersizeEditFieldLabel.Text = 'Tukey window filter size';
            app.TukeywindowfiltersizeEditField = uieditfield(app.ProcessingOptionGridLayout, 'numeric');
            app.TukeywindowfiltersizeEditField.ValueDisplayFormat = '%1.4g';
            app.TukeywindowfiltersizeEditField.Tag = 'Tukey';
            app.TukeywindowfiltersizeEditField.Tooltip = {'Recommendation:'; 'Philips - 0'; 'Siemens, GE - 0.4'};
            app.TukeywindowfiltersizeEditField.Value = 0.4;
            app.StartChiseparationButton = uibutton(app.ProcessingOptionGridLayout, 'push');
            app.StartChiseparationButton.ButtonPushedFcn = createCallbackFcn(app, @StartChiseparationButtonPushed, true);
            app.StartChiseparationButton.Text = {'Start'; 'Chi-separation'};
            app.ChiseparationLabel = uilabel(app.ProcessingOptionGridLayout);
            app.ChiseparationLabel.Text = 'Chi-separation:';
            app.ChiseparationDropDown = uidropdown(app.ProcessingOptionGridLayout);
            app.ChiseparationDropDown.Items = {'Chi-sepnet-R2*', 'Chi-sepnet-R2''', 'Chi-separation (MEDI)', 'Chi-separation (iLSQR)'};
            app.ChiseparationDropDown.Tag = 'Chisep';
            app.ChiseparationDropDown.Value = 'Chi-sepnet-R2*';
            app.R2fittingDropDownLabel = uilabel(app.ProcessingOptionGridLayout);
            app.R2fittingDropDownLabel.Text = 'R2* fitting:';
            app.R2fittingDropDown = uidropdown(app.ProcessingOptionGridLayout);
            app.R2fittingDropDown.Items = {'ARLO', 'NLLS fitting', 'Use preprocessed R2* or R2'' map'};
            app.R2fittingDropDown.Tag = 'R2s';
            app.R2fittingDropDown.Tooltip = {'ARLO: Fast method for exponential fitting.'; ''; 'NNLS fitting: Takes long time, use when ARLO is not available'};
            app.R2fittingDropDown.Value = 'ARLO';
            app.PhaseInverseCheckBox = uicheckbox(app.ProcessingOptionGridLayout);
            app.PhaseInverseCheckBox.Text = 'Inverse phase map polarity';
            app.ResolutionPreserveCheckBox = uicheckbox(app.ProcessingOptionGridLayout);
            app.ResolutionPreserveCheckBox.Text = {'Resolution-generalization'; 'for chi-sepnet'};
            app.MultiechomagnitudedenoisingMGREforR2mappingCheckBox = uicheckbox(app.ProcessingOptionGridLayout);
            app.MultiechomagnitudedenoisingMGREforR2mappingCheckBox.Tag = 'Denoise';
            app.MultiechomagnitudedenoisingMGREforR2mappingCheckBox.Text = {'Multi-echo magnitude denoising '; '(MGRE for R2* mapping)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChisepToolbox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end