classdef SelectMatVariablesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectButton                 matlab.ui.control.Button
        SelectMatFileVariablesLabel  matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        callingapp % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, varlist)
            app.callingapp = mainapp;
            app.ListBox.Items = varlist;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.callingapp.varname = app.ListBox.Value;

            try uifigure = app.callingapp.UIFigure; catch; uifigure = app.callingapp.UIFigure; end
            uiresume(uifigure);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.varname = app.ListBox.Value;
            
            % Enable the Plot Options button in main app
            try uifigure = app.callingapp.UIFigure; catch; uifigure = app.callingapp.UIFigure; end
            uiresume(uifigure);
            % Delete the dialog box  
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ListBox = uilistbox(app.UIFigure);
            app.SelectMatFileVariablesLabel = uilabel(app.UIFigure);
            app.SelectMatFileVariablesLabel.Text = 'Select Mat File Variables';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectMatVariablesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end