classdef vesselSeg_Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageDisplayPanel          matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        SliceSliderLabel           matlab.ui.control.Label
        SliceSlider                matlab.ui.control.Slider
        AnisoSlider                matlab.ui.control.Slider
        AnisoSliderLabel           matlab.ui.control.Label
        ImageDisplayDropDown       matlab.ui.control.DropDown
        ManualCheckBox             matlab.ui.control.CheckBox
        ImageDisplay               matlab.ui.control.UIAxes
        ImageDisplay_2             matlab.ui.control.UIAxes
        OptionsPanel               matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        InputTypeDropDown          matlab.ui.control.DropDown
        InputTypeLabel             matlab.ui.control.Label
        ParametersLabel            matlab.ui.control.Label
        StartButton                matlab.ui.control.StateButton
        Gamma1EditFieldLabel       matlab.ui.control.Label
        Gamma1EditField            matlab.ui.control.NumericEditField
        Gamma2EditFieldLabel       matlab.ui.control.Label
        Gamma2EditField            matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel    matlab.ui.control.Label
        VoxelsizeEditField         matlab.ui.control.EditField
        EndButton                  matlab.ui.control.StateButton
        InputDataPanel1            matlab.ui.container.Panel
        OupurDirLoadButton         matlab.ui.control.StateButton
        BrainMaskLoadButton        matlab.ui.control.StateButton
        chiDiaLoadButton           matlab.ui.control.StateButton
        chiParaLoadButton          matlab.ui.control.StateButton
        r2starLoadButton           matlab.ui.control.StateButton
        R2starEditField            matlab.ui.control.EditField
        R2starEditFieldLabel       matlab.ui.control.Label
        x_paraEditField            matlab.ui.control.EditField
        x_paraEditFieldLabel       matlab.ui.control.Label
        x_diaEditField             matlab.ui.control.EditField
        x_diaEditFieldLabel        matlab.ui.control.Label
        BrainMaskEditField         matlab.ui.control.EditField
        BrainMaskEditFieldLabel    matlab.ui.control.Label
        OutputDirEditField         matlab.ui.control.EditField
        OutputDirEditFieldLabel    matlab.ui.control.Label
        InputDataPanel2            matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        BrainMaskLoadButton_4      matlab.ui.control.StateButton
        BrainMaskLoadButton_3      matlab.ui.control.StateButton
        SeedInput1LoadButton_2     matlab.ui.control.StateButton
        ImageEditField             matlab.ui.control.EditField
        BrainMaskEditField_2       matlab.ui.control.EditField
        BrainMaskEditField_2Label  matlab.ui.control.Label
        OutputDirEditField_2       matlab.ui.control.EditField
        OutputDirEditField_2Label  matlab.ui.control.Label
        ImageTypeDropDown          matlab.ui.control.DropDown
        WhiteCheckBox              matlab.ui.control.CheckBox
        BlackCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        
        HomeDirectory;
        data;
        output_dir;
        images;
        params;


        varname;
        
        latestpath;

        range;
        dispRange;
        curSlice;
        curAniso;
        initialcoord;
        Win;
        Lev;
        WLAdjCoe;
        
    end
    
    
    methods (Access = private)
        function ScrollSlice(app,~,event)

            uiSlider = findobj(app.UIFigure, 'Type', 'uislider', '-and', 'Tag', 'Display');

            if event.VerticalScrollCount < 0
                app.curSlice = app.curSlice + 1;
                if(app.curSlice>uiSlider.Limits(2)); app.curSlice = uiSlider.Limits(2); end
                uiSlider.Value = app.curSlice;
            elseif event.VerticalScrollCount > 0
                app.curSlice = app.curSlice - 1;
                if(app.curSlice<1); app.curSlice = 1; end
                uiSlider.Value = app.curSlice;
            end
            
            app.ManualCheckBox.Value = 0;
            UpdateImageDisplay_vesselSeg(app);

        end

        function ScrollAniso(app,~,event)

            uiSlider = findobj(app.UIFigure, 'Type', 'uislider', '-and', 'Tag', 'Aniso');

            if event.VerticalScrollCount < 0
                app.curAniso = app.curAniso + 1;
                if(app.curAniso>uiSlider.Limits(2)); app.curAniso = uiSlider.Limits(2); end
                uiSlider.Value = app.curAniso;
            elseif event.VerticalScrollCount > 0
                app.curAniso = app.curAniso - 1;
                if(app.curAniso<1); app.curAniso = 1; end
                uiSlider.Value = app.curAniso;
            end

            if strcmp(app.ImageDisplayDropDown.Value, 'x_para')
                thred = app.curAniso * 0.00003;
                app.AnisoSliderLabel.Text = sprintf("Aniso %1.5f", thred);
                app.data.OutputData.paraMask = filterVesselsByAnisotropy_GUI(app.data.ProcessedData.paraMask_init, ...
                                                    app.data.ProcessedData.homo1, thred);
            elseif strcmp(app.ImageDisplayDropDown.Value, 'x_dia')
                thred = app.curAniso * 0.00003;
                app.AnisoSliderLabel.Text = sprintf("Aniso %1.5f", thred);
                app.data.OutputData.diaMask = filterVesselsByAnisotropy_GUI(app.data.ProcessedData.diaMask_init, ...
                                                    app.data.ProcessedData.homo2, thred);
            else
                thred = app.curAniso * 0.00003;
                app.AnisoSliderLabel.Text = sprintf("Aniso %1.5f", thred);
                app.data.OutputData.vesselMask = filterVesselsByAnisotropy_GUI(app.data.ProcessedData.vesselMask_init, ...
                    app.data.ProcessedData.homo1, thred);
            end

            UpdateImageDisplay_vesselSeg(app);

        end

        function WinLevAdj(app,~,~)
            PosDiff = app.UIFigure.CurrentPoint - app.initialcoord;
            
            app.R2WL();
            app.Win = app.Win + PosDiff(1) * app.WLAdjCoe;
            app.Lev = app.Lev - PosDiff(2) * app.WLAdjCoe;
            app.WL2R();

            if(abs(app.range(1))>=1)
                set(app.RminEditField, 'Value', sprintf('%6.2f',app.range(1)));
            else
                set(app.RminEditField, 'Value', sprintf('%1.4f',app.range(1)));
            end
            if(abs(app.range(2))>=1)
                set(app.RmaxEditField, 'Value', sprintf('%6.2f',app.range(2)));
            else
                set(app.RmaxEditField, 'Value', sprintf('%1.4f',app.range(2)));
            end
        end

        function mouseClick(app,~,event)
            cp = app.UIFigure.CurrentPoint;
            isInImage1 = cp(1) >= app.ImageDisplay.Position(1)+app.ImageDisplayPanel.Position(1) && ...
                cp(1) <= sum(app.ImageDisplay.Position([1,3]))+app.ImageDisplayPanel.Position(1) && ...
                cp(2) >= app.ImageDisplay.Position(2)+app.ImageDisplayPanel.Position(2) && ...
                cp(2) <= sum(app.ImageDisplay.Position([2,4]))+app.ImageDisplayPanel.Position(2);
            isInDispPanel1 = cp(1) >= app.ImageDisplayPanel.Position(1) && ...
                cp(1) <= sum(app.ImageDisplayPanel.Position([1,3])) && ...
                cp(2) >= app.ImageDisplayPanel.Position(2) && ...
                cp(2) <= sum(app.ImageDisplayPanel.Position([2,4]));

            isInImage2 = cp(1) >= app.ImageDisplay_2.Position(1)+app.ImageDisplayPanel.Position(1) && ...
                cp(1) <= sum(app.ImageDisplay_2.Position([1,3]))+app.ImageDisplayPanel.Position(1) && ...
                cp(2) >= app.ImageDisplay_2.Position(2)+app.ImageDisplayPanel.Position(2) && ...
                cp(2) <= sum(app.ImageDisplay_2.Position([2,4]))+app.ImageDisplayPanel.Position(2);
            isInDispPanel2 = cp(1) >= app.ImageDisplayPanel.Position(1) && ...
                cp(1) <= sum(app.ImageDisplayPanel.Position([1,3])) && ...
                cp(2) >= app.ImageDisplayPanel.Position(2) && ...
                cp(2) <= sum(app.ImageDisplayPanel.Position([2,4]));

            switch event.Source.SelectionType
                case 'alt'
                    app.initialcoord = cp;
                    if isInImage1 && isInImage2
                        set(app.UIFigure, 'WindowButtonMotionFcn', @app.WinLevAdj);
                    end
                case 'normal'
                    if isInDispPanel1 && isInDispPanel2
                        set(app.UIFigure, 'WindowScrollWheelFcn', @app.ScrollSlice);
                        disableDefaultInteractivity(app.ImageDisplay)
                        disalbeDefaultInteractivity(app.ImageDisplay_2)
                    else
                        set(app.UIFigure, 'WindowScrollWheelFcn', '');
%                         enableDefaultInteractivity(app.ImageDisplay)
                    end
            end
        end
        
        function R2WL(app)
            app.Lev = (app.range(1)+app.range(2))/2;
            app.Win = app.range(2)-app.range(1);
            if(app.Win<0)
                app.Win=eps;
            end
        end
        
        function WL2R(app)
            app.range(1) = app.Lev-app.Win/2;
            app.range(2) = app.Lev+app.Win/2;
            if(app.range(2)<app.range(1))
                app.range(2) = app.range(1)+eps;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, output_dir)
            % Data
            input_data = struct('x_para', [], 'x_dia', [], 'QSM', [], 'r2star', [], 'SWI', [], 'Others', [],'BrainMask', []);
            parameters = struct('tau', [], 'tau2', [], 'D', [], 'spacing', [], 'scales', [], 'sigmas', [], ...
                            'alpha', [], 'whiteondark', [], 'mipSlice', [], 'overlap', [], 'beta', [], 'similarity', [], ...
                            'gamma1', [], 'gamma2', [], 'voxel_size', []);   
            processed_data = struct('paraMask_init', [], 'diaMask_init', [], 'vesselMask_init', [], ...
                                        'homo1', [], 'homo2', []);
            output_data = struct('paraMask', [], 'diaMask', [], 'vesselMask', []);
            
            app.data = struct('InputData', input_data, 'OutputData', output_data, 'ProcessedData', processed_data, 'params',parameters);

            if(exist('data','var'))
                app.data.InputData = data;
                
                app.InputTypeDropDown.Enable = false;
                
                
                app.x_diaEditField.Value = "loaded from chisep toolbox";
                app.x_paraEditField.Value = "loaded from chisep toolbox";
                app.R2starEditField.Value = "loaded from chisep toolbox";
                app.BrainMaskEditField.Value = "loaded from chisep toolbox";
                app.OutputDirEditField.Value = "loaded from chisep toolbox";
                
                app.x_diaEditField.Enable = false;
                app.x_paraEditField.Enable = false;
                app.R2starEditField.Enable = false;
                app.BrainMaskEditField.Enable = false;
                app.OutputDirEditField.Enable = false;
                
                app.chiDiaLoadButton.Enable = false;
                app.chiParaLoadButton.Enable = false;
                app.r2starLoadButton.Enable = false;
                app.BrainMaskLoadButton.Enable = false;
                app.OupurDirLoadButton.Enable = false;
            end
            if(exist("output_dir",'var'))
                app.output_dir = output_dir;
            else
                app.output_dir = '';
            end
            
            app.latestpath = pwd;
            app.curSlice = 1;
            app.range = [0 0.2];
            
            app.dispRange.x_para = [0 0.2];
            app.dispRange.x_dia = [0 0.2];
            app.dispRange.r2star = [0 50];
            app.dispRange.QSM = [0 0.15];
            
            set(app.InputDataPanel1,'visible','on');
            set(app.InputDataPanel2,'visible','off');
        end

        % Value changed function: StartButton
        function StartVesselSegButtonPushed(app, event)
            RunVesselSeg(app);
        end

        % Value changed function: BrainMaskLoadButton, 
        % BrainMaskLoadButton_3, BrainMaskLoadButton_4, 
        % OupurDirLoadButton, SeedInput1LoadButton_2, 
        % chiDiaLoadButton, chiParaLoadButton, r2starLoadButton
        function LoadButtonPushed(app, event)
            LoadButtonPushedCallback_vesselSeg(app, event)
        end

        % Value changed function: InputTypeDropDown
        function InputTypeDropDownValueChanged(app, event)
            switch app.InputTypeDropDown.Value
                case 'Chi-separation'
                    set(app.InputDataPanel1,'visible','on');
                    set(app.InputDataPanel2,'visible','off');
                case 'Others'
                    set(app.InputDataPanel1,'visible','off');
                    set(app.InputDataPanel2,'visible','on');        
            end         
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.curSlice = round(event.Value);
            
            set(app.UIFigure, 'WindowScrollWheelFcn', @app.ScrollSlice);
            disableDefaultInteractivity(app.ImageDisplay)
            disableDefaultInteractivity(app.ImageDisplay_2)
            
            UpdateImageDisplay_vesselSeg(app);
        end

        % Value changed function: AnisoSlider
        function AnisoSliderValueChanged(app, event)
            app.curAniso = round(event.Value);

            set(app.UIFigure, 'WindowScrollWheelFcn', @app.ScrollAniso);
            disableDefaultInteractivity(app.ImageDisplay)
            disableDefaultInteractivity(app.ImageDisplay_2)
            
            if strcmp(app.ImageDisplayDropDown.Value, 'x_para')
                thred = app.curAniso * 0.00003;
                app.AnisoSliderLabel.Text = sprintf("Aniso %1.5f", thred);
                app.data.OutputData.paraMask = filterVesselsByAnisotropy_GUI(app.data.ProcessedData.paraMask_init, ...
                    app.data.ProcessedData.homo1, thred);
            elseif strcmp(app.ImageDisplayDropDown.Value, 'x_dia')
                thred = app.curAniso * 0.00003;
                app.AnisoSliderLabel.Text = sprintf("Aniso %1.5f", thred);
                app.data.OutputData.diaMask = filterVesselsByAnisotropy_GUI(app.data.ProcessedData.diaMask_init, ...
                    app.data.ProcessedData.homo2, thred);
            else
                thred = app.curAniso * 0.00003;
                app.AnisoSliderLabel.Text = sprintf("Aniso %1.5f", thred);
                app.data.OutputData.vesselMask = filterVesselsByAnisotropy_GUI(app.data.ProcessedData.vesselMask_init, ...
                    app.data.ProcessedData.homo1, thred);
            end

            UpdateImageDisplay_vesselSeg(app);
        end

        % Value changed function: EndButton
        function EndButtonValueChanged(app, event)
            saveData_vesselSeg(app)
        end

        % Value changed function: ImageDisplayDropDown
        function ImageDisplayDropDownValueChanged(app, event)
            UpdateImageDisplay_vesselSeg(app);
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            if event.Source.Value == 1
                ManuallyRemoveCluster_vesselSeg(app);
            end
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            value = app.ImageTypeDropDown.Value;
            switch value
                case {'R2star','QSM'}
                    app.WhiteCheckBox.Value = 1;
                    app.BlackCheckBox.Value = 0;
                case 'SWI'
                    app.WhiteCheckBox.Value = 0;
                    app.BlackCheckBox.Value = 1;
                case 'Others'
                    app.WhiteCheckBox.Value = 0;
                    app.BlackCheckBox.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageDisplayPanel = uipanel(app.UIFigure);
            app.ImageDisplayPanel.Title = 'Image Display';
            app.GridLayout = uigridlayout(app.ImageDisplayPanel);
            app.SliceSliderLabel = uilabel(app.GridLayout);
            app.SliceSliderLabel.Tag = 'Display';
            app.SliceSliderLabel.Text = 'Slice          ###/###';
            app.SliceSlider = uislider(app.GridLayout);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.MajorTickLabels = {''};
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Tag = 'Display';
            app.SliceSlider.Value = 50;
            app.AnisoSlider = uislider(app.GridLayout);
            app.AnisoSlider.Limits = [0 1200];
            app.AnisoSlider.MajorTicks = [];
            app.AnisoSlider.MajorTickLabels = {''};
            app.AnisoSlider.ValueChangedFcn = createCallbackFcn(app, @AnisoSliderValueChanged, true);
            app.AnisoSlider.MinorTicks = [];
            app.AnisoSlider.Tag = 'Aniso';
            app.AnisoSlider.Value = 400;
            app.AnisoSliderLabel = uilabel(app.GridLayout);
            app.AnisoSliderLabel.Tag = 'Aniso';
            app.AnisoSliderLabel.Text = 'Anisotropy ###/###';
            app.ImageDisplayDropDown = uidropdown(app.GridLayout);
            app.ImageDisplayDropDown.Items = {'no image'};
            app.ImageDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDisplayDropDownValueChanged, true);
            app.ImageDisplayDropDown.Tag = 'Display';
            app.ImageDisplayDropDown.Value = 'no image';
            app.ManualCheckBox = uicheckbox(app.GridLayout);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = '';
            app.ImageDisplay = uiaxes(app.GridLayout);
            app.ImageDisplay.XTick = [];
            app.ImageDisplay.YTick = [];
            app.ImageDisplay.YTickLabel = '';
            app.ImageDisplay_2 = uiaxes(app.GridLayout);
            app.ImageDisplay_2.XTick = [];
            app.ImageDisplay_2.YTick = [];
            app.ImageDisplay_2.YTickLabel = '';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout2 = uigridlayout(app.OptionsPanel);
            app.InputTypeDropDown = uidropdown(app.GridLayout2);
            app.InputTypeDropDown.Items = {'Chi-separation', 'Others'};
            app.InputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputTypeDropDownValueChanged, true);
            app.InputTypeDropDown.Value = 'Chi-separation';
            app.InputTypeLabel = uilabel(app.GridLayout2);
            app.InputTypeLabel.Text = 'Input Type';
            app.ParametersLabel = uilabel(app.GridLayout2);
            app.ParametersLabel.Text = 'Parameters';
            app.StartButton = uibutton(app.GridLayout2, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartVesselSegButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Gamma1EditFieldLabel = uilabel(app.GridLayout2);
            app.Gamma1EditFieldLabel.Text = 'Gamma1';
            app.Gamma1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Gamma1EditField.Tag = 'gamma1';
            app.Gamma1EditField.Value = 0.5;
            app.Gamma2EditFieldLabel = uilabel(app.GridLayout2);
            app.Gamma2EditFieldLabel.Text = 'Gamma2';
            app.Gamma2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Gamma2EditField.Tag = 'gamm2';
            app.Gamma2EditField.Value = -0.5;
            app.VoxelsizeEditFieldLabel = uilabel(app.GridLayout2);
            app.VoxelsizeEditFieldLabel.Text = 'Voxel size';
            app.VoxelsizeEditField = uieditfield(app.GridLayout2, 'text');
            app.VoxelsizeEditField.Tag = 'voxel_size';
            app.VoxelsizeEditField.Value = '[1,1,1]';
            app.EndButton = uibutton(app.GridLayout2, 'state');
            app.EndButton.ValueChangedFcn = createCallbackFcn(app, @EndButtonValueChanged, true);
            app.EndButton.Text = 'End';
            app.InputDataPanel1 = uipanel(app.GridLayout2);
            app.InputDataPanel1.Title = 'Input Data';
            app.OupurDirLoadButton = uibutton(app.InputDataPanel1, 'state');
            app.OupurDirLoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.OupurDirLoadButton.Tag = 'output_dir';
            app.OupurDirLoadButton.Text = 'load';
            app.BrainMaskLoadButton = uibutton(app.InputDataPanel1, 'state');
            app.BrainMaskLoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.BrainMaskLoadButton.Tag = 'BrainMask';
            app.BrainMaskLoadButton.Text = 'load';
            app.chiDiaLoadButton = uibutton(app.InputDataPanel1, 'state');
            app.chiDiaLoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.chiDiaLoadButton.Tag = 'x_dia';
            app.chiDiaLoadButton.Text = 'load';
            app.chiParaLoadButton = uibutton(app.InputDataPanel1, 'state');
            app.chiParaLoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.chiParaLoadButton.Tag = 'x_para';
            app.chiParaLoadButton.Text = 'load';
            app.r2starLoadButton = uibutton(app.InputDataPanel1, 'state');
            app.r2starLoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.r2starLoadButton.Tag = 'r2star';
            app.r2starLoadButton.Text = 'load';
            app.R2starEditField = uieditfield(app.InputDataPanel1, 'text');
            app.R2starEditField.Tag = 'r2star';
            app.R2starEditFieldLabel = uilabel(app.InputDataPanel1);
            app.R2starEditFieldLabel.Text = 'R2star';
            app.x_paraEditField = uieditfield(app.InputDataPanel1, 'text');
            app.x_paraEditField.Tag = 'x_para';
            app.x_paraEditFieldLabel = uilabel(app.InputDataPanel1);
            app.x_paraEditFieldLabel.Text = 'x_para';
            app.x_diaEditField = uieditfield(app.InputDataPanel1, 'text');
            app.x_diaEditField.Tag = 'x_dia';
            app.x_diaEditFieldLabel = uilabel(app.InputDataPanel1);
            app.x_diaEditFieldLabel.Text = 'x_dia';
            app.BrainMaskEditField = uieditfield(app.InputDataPanel1, 'text');
            app.BrainMaskEditField.Tag = 'BrainMask';
            app.BrainMaskEditFieldLabel = uilabel(app.InputDataPanel1);
            app.BrainMaskEditFieldLabel.Text = 'BrainMask';
            app.OutputDirEditField = uieditfield(app.InputDataPanel1, 'text');
            app.OutputDirEditField.Tag = 'output_dir';
            app.OutputDirEditFieldLabel = uilabel(app.InputDataPanel1);
            app.OutputDirEditFieldLabel.Text = 'Output Dir';
            app.InputDataPanel2 = uipanel(app.GridLayout2);
            app.InputDataPanel2.Title = 'Input Data';
            app.GridLayout3 = uigridlayout(app.InputDataPanel2);
            app.BrainMaskLoadButton_4 = uibutton(app.GridLayout3, 'state');
            app.BrainMaskLoadButton_4.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.BrainMaskLoadButton_4.Tag = 'output_dir';
            app.BrainMaskLoadButton_4.Text = 'load';
            app.BrainMaskLoadButton_3 = uibutton(app.GridLayout3, 'state');
            app.BrainMaskLoadButton_3.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.BrainMaskLoadButton_3.Tag = 'BrainMask';
            app.BrainMaskLoadButton_3.Text = 'load';
            app.SeedInput1LoadButton_2 = uibutton(app.GridLayout3, 'state');
            app.SeedInput1LoadButton_2.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.SeedInput1LoadButton_2.Tag = 'Img';
            app.SeedInput1LoadButton_2.Text = 'load';
            app.ImageEditField = uieditfield(app.GridLayout3, 'text');
            app.ImageEditField.Tag = 'Img';
            app.BrainMaskEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.BrainMaskEditField_2.Tag = 'BrainMask';
            app.BrainMaskEditField_2Label = uilabel(app.GridLayout3);
            app.BrainMaskEditField_2Label.Text = 'BrainMask';
            app.OutputDirEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.OutputDirEditField_2.Tag = 'output_dir';
            app.OutputDirEditField_2Label = uilabel(app.GridLayout3);
            app.OutputDirEditField_2Label.Text = 'Output Dir';
            app.ImageTypeDropDown = uidropdown(app.GridLayout3);
            app.ImageTypeDropDown.Items = {'r2star', 'QSM', 'SWI', 'Others'};
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown.Tag = 'Img';
            app.ImageTypeDropDown.Value = 'r2star';
            app.WhiteCheckBox = uicheckbox(app.GridLayout3);
            app.WhiteCheckBox.Text = 'White';
            app.WhiteCheckBox.Value = true;
            app.BlackCheckBox = uicheckbox(app.GridLayout3);
            app.BlackCheckBox.Text = 'Black';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vesselSeg_Toolbox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end