classdef SetToolBoxDirectoryApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Field_MRItools            matlab.ui.control.EditField
        mritoolsLabel             matlab.ui.control.Label
        loadButton_MRItools       matlab.ui.control.Button
        Field_SEGUE               matlab.ui.control.EditField
        SEGUELabel                matlab.ui.control.Label
        loadButton_SEGUE          matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        Field_STISuitePath        matlab.ui.control.EditField
        STISuiteLabel             matlab.ui.control.Label
        loadButton_STISuite       matlab.ui.control.Button
        Field_MEDIPath            matlab.ui.control.EditField
        MEDILabel                 matlab.ui.control.Label
        loadButton_MEDI           matlab.ui.control.Button
        SetToolBoxDirectoryLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        HomeDirectory % Description

        % ToolBox Direcotry
        MEDIDirectory='';
        STISuiteDirectory='';
        SEGUEDirectory='';
        MRItoolsDirectory='';
%         ToolBoxDirectory = struct('MEDIDirectory', '', 'STISuiteDirectory', '', 'SEGUEDirectory', '', 'MRItoolsDirectory', '');
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, HomeDirectory)
            app.HomeDirectory = HomeDirectory;
        end

        % Button pushed function: loadButton_MEDI
        function loadButton_MEDIPushed(app, event)
            uiAllButton = findobj(app.UIFigure,'Type','uibutton');
            set(uiAllButton,'Enable','off')

            path = uigetdir;
            
            if isequal(path,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', path]);
               app.MEDIDirectory = path;
               app.Field_MEDIPath.Value = path;
            end
            
            set(uiAllButton,'Enable','on')
        end

        % Button pushed function: loadButton_STISuite
        function loadButton_STISuitePushed(app, event)
            uiAllButton = findobj(app.UIFigure,'Type','uibutton');
            set(uiAllButton,'Enable','off')

            path = uigetdir;
            
            if isequal(path,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', path]);
               app.STISuiteDirectory = path;
               app.Field_STISuitePath.Value = path;
            end
            
            set(uiAllButton,'Enable','on')
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
%             app.callingapp.ToolBoxDirectory = app.ToolBoxDirectory;
            MEDIDirectory = app.MEDIDirectory;
            STISuiteDirectory = app.STISuiteDirectory;
            SEGUEDirectory = app.SEGUEDirectory;
            MRItoolsDirectory = app.MRItoolsDirectory;
            
            save([app.HomeDirectory filesep 'initialize' filesep 'ToolboxDirectory.mat'],'MEDIDirectory',...
                'STISuiteDirectory', 'SEGUEDirectory', 'MRItoolsDirectory')
            % Delete the dialog box
%             uiresume(app.callingapp.UIFigure);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             app.callingapp.ToolBoxDirectory = app.ToolBoxDirectory;
            MEDIDirectory = app.MEDIDirectory;
            STISuiteDirectory = app.STISuiteDirectory;
            SEGUEDirectory = app.SEGUEDirectory;
            MRItoolsDirectory = app.MRItoolsDirectory;
            
            save([app.HomeDirectory filesep 'initialize' filesep 'ToolboxDirectory.mat'],'MEDIDirectory',...
                'STISuiteDirectory', 'SEGUEDirectory', 'MRItoolsDirectory')
            % Delete the dialog box
%             uiresume(app.callingapp.UIFigure);
            delete(app)            
        end

        % Button pushed function: loadButton_SEGUE
        function loadButton_SEGUEPushed(app, event)
            uiAllButton = findobj(app.UIFigure,'Type','uibutton');
            set(uiAllButton,'Enable','off')

            path = uigetdir;
            
            if isequal(path,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', path]);
               app.SEGUEDirectory = path;
               app.Field_SEGUE.Value = path;
            end
            
            set(uiAllButton,'Enable','on')
        end

        % Button pushed function: loadButton_MRItools
        function loadButton_MRItoolsPushed(app, event)
            uiAllButton = findobj(app.UIFigure,'Type','uibutton');
            set(uiAllButton,'Enable','off')

            path = uigetdir;
            
            if isequal(path,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', path]);
               app.MRItoolsDirectory = path;
               app.Field_MRItools.Value = path;
            end
            
            set(uiAllButton,'Enable','on')
        end

        % Value changed function: Field_MEDIPath, Field_MRItools, 
        % Field_SEGUE, Field_STISuitePath
        function PathValueChanged(app, event)
            value = event.Value;
            tag = event.Source.Tag;
            switch tag
                case 'MEDI'
                    app.MEDIDirectory = value;
                case 'STI'
                    app.STISuiteDirectory = value;
                case 'SEGUE'
                    app.SEGUEDirectory = value;
                case 'MRItools'
                    app.MRItoolsDirectory = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Field_MRItools = uieditfield(app.GridLayout, 'text');
            app.Field_MRItools.ValueChangedFcn = createCallbackFcn(app, @PathValueChanged, true);
            app.Field_MRItools.Tag = 'MRItools';
            app.mritoolsLabel = uilabel(app.GridLayout);
            app.mritoolsLabel.Tag = 'MRItools';
            app.mritoolsLabel.Text = 'mritools:';
            app.loadButton_MRItools = uibutton(app.GridLayout, 'push');
            app.loadButton_MRItools.ButtonPushedFcn = createCallbackFcn(app, @loadButton_MRItoolsPushed, true);
            app.loadButton_MRItools.Tag = 'MRItools';
            app.loadButton_MRItools.Text = 'load';
            app.Field_SEGUE = uieditfield(app.GridLayout, 'text');
            app.Field_SEGUE.ValueChangedFcn = createCallbackFcn(app, @PathValueChanged, true);
            app.Field_SEGUE.Tag = 'SEGUE';
            app.SEGUELabel = uilabel(app.GridLayout);
            app.SEGUELabel.Tag = 'SEGUE';
            app.SEGUELabel.Text = 'SEGUE:';
            app.loadButton_SEGUE = uibutton(app.GridLayout, 'push');
            app.loadButton_SEGUE.ButtonPushedFcn = createCallbackFcn(app, @loadButton_SEGUEPushed, true);
            app.loadButton_SEGUE.Tag = 'SEGUE';
            app.loadButton_SEGUE.Text = 'load';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Field_STISuitePath = uieditfield(app.GridLayout, 'text');
            app.Field_STISuitePath.ValueChangedFcn = createCallbackFcn(app, @PathValueChanged, true);
            app.Field_STISuitePath.Tag = 'STI';
            app.STISuiteLabel = uilabel(app.GridLayout);
            app.STISuiteLabel.Tag = 'STI';
            app.STISuiteLabel.Text = 'STI Suite:';
            app.loadButton_STISuite = uibutton(app.GridLayout, 'push');
            app.loadButton_STISuite.ButtonPushedFcn = createCallbackFcn(app, @loadButton_STISuitePushed, true);
            app.loadButton_STISuite.Tag = 'STI';
            app.loadButton_STISuite.Text = 'load';
            app.Field_MEDIPath = uieditfield(app.GridLayout, 'text');
            app.Field_MEDIPath.ValueChangedFcn = createCallbackFcn(app, @PathValueChanged, true);
            app.Field_MEDIPath.Tag = 'MEDI';
            app.MEDILabel = uilabel(app.GridLayout);
            app.MEDILabel.Tag = 'MEDI';
            app.MEDILabel.Text = 'MEDI:';
            app.loadButton_MEDI = uibutton(app.GridLayout, 'push');
            app.loadButton_MEDI.ButtonPushedFcn = createCallbackFcn(app, @loadButton_MEDIPushed, true);
            app.loadButton_MEDI.Tag = 'MEDI';
            app.loadButton_MEDI.Text = 'load';
            app.SetToolBoxDirectoryLabel = uilabel(app.GridLayout);
            app.SetToolBoxDirectoryLabel.Text = 'Set ToolBox Directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetToolBoxDirectoryApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end